classdef HVRT_para_calc < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure           matlab.ui.Figure
        AboutMenu          matlab.ui.container.Menu
        R1EditFieldLabel   matlab.ui.control.Label
        R1EditField        matlab.ui.control.NumericEditField
        LEditFieldLabel    matlab.ui.control.Label
        LEditField         matlab.ui.control.NumericEditField
        CalculateButton    matlab.ui.control.Button
        dipEditFieldLabel  matlab.ui.control.Label
        dipEditField       matlab.ui.control.NumericEditField
        QfEditFieldLabel   matlab.ui.control.Label
        QfEditField        matlab.ui.control.NumericEditField
        UITable            matlab.ui.control.Table
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: CalculateButton
        function CalculateButtonPushed(app, event)
            R1 = app.R1EditField.Value;
            L = app.LEditField.Value;
            d = app.dipEditField.Value;
            Q = app.QfEditField.Value;
            syms C R2;
            w = 100*pi;
            eqns = [sqrt(R2^2 + (1/(w*C))^2) / sqrt( (R1+R2)^2 + (w*L - 1/(w*C))^2 ) == d,...
                    sqrt(L/C)/(R1+R2) == Q];
            vars = [C, R2];
            [solveC, solveR2] = solve(eqns, vars);
            solveC = double(solveC);
            solveR2 = double(solveR2);
            ans_len = size(solveC, 1);
            if ans_len == 0
                app.TextArea.Value = 'No solution !';
            else
                ans_tab = table(solveR2, solveC, 'VariableNames', {'R2(Ohm)', 'C(F)'});
            end
            app.UITable.Data = ans_tab;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.AboutMenu = uimenu(app.UIFigure);
            app.AboutMenu.Text = 'About';
            app.R1EditFieldLabel = uilabel(app.UIFigure);
            app.R1EditFieldLabel.Text = 'R1';
            app.R1EditField = uieditfield(app.UIFigure, 'numeric');
            app.R1EditField.Value = 1.350335031;
            app.LEditFieldLabel = uilabel(app.UIFigure);
            app.LEditFieldLabel.Text = 'L';
            app.LEditField = uieditfield(app.UIFigure, 'numeric');
            app.LEditField.Value = 0.304917565;
            app.CalculateButton = uibutton(app.UIFigure, 'push');
            app.CalculateButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateButtonPushed, true);
            app.CalculateButton.Text = 'Calculate';
            app.dipEditFieldLabel = uilabel(app.UIFigure);
            app.dipEditFieldLabel.Text = 'dip';
            app.dipEditField = uieditfield(app.UIFigure, 'numeric');
            app.dipEditField.Value = 1.3;
            app.QfEditFieldLabel = uilabel(app.UIFigure);
            app.QfEditFieldLabel.Text = 'Qf';
            app.QfEditField = uieditfield(app.UIFigure, 'numeric');
            app.QfEditField.Value = 1;
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'R2(Ohm)'; 'C(F)'};
            app.UITable.RowName = {};
            app.UITable.ColumnEditable = true;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = HVRT_para_calc

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end